classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        Button                    matlab.ui.control.StateButton
        DivisionsEditFieldLabel   matlab.ui.control.Label
        DivisionsEditField        matlab.ui.control.NumericEditField
        Focus_point_1SliderLabel  matlab.ui.control.Label
        Focus_point_1Slider       matlab.ui.control.Slider
        Focus_point_2SliderLabel  matlab.ui.control.Label
        Focus_point_2Slider       matlab.ui.control.Slider
        UITable                   matlab.ui.control.Table
    end

properties(Access = public) 
data = [];
x = [];
y =[];
z = [];
fp1_value = [];
fp2_value = [];
end

    methods (Access = private)
    
        function [fp1_value , fp2_value] = update_plot(app)

%app.fp1_value = floor(app.Focus_point_1Slider.Value - app.Focus_point_1Slider.Limits(1) + 1);
app.fp1_value = floor(app.Focus_point_1Slider.Value);
%app.fp2_value = floor(app.Focus_point_2Slider.Value - app.Focus_point_2Slider.Limits(1) + 1);
app.fp2_value = floor(app.Focus_point_2Slider.Value);
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
fp1_row = app.data(app.fp1_value, :);
fp2_row = app.data(app.fp2_value, :);
%get max value of the two rows plotted
fp1_row_max = max(fp1_row);
fp2_row_max = max(fp2_row);
fprow_max = max(fp1_row_max,fp2_row_max);
% absolute difference between fp1_row and fp2_row store as fp_diff
fp_diff = abs( fp1_row - fp2_row ) ; 
% make the value of 10 variable 
var = app.DivisionsEditField.Value; 
slice = fp_diff ( 1:1 , 1:var:end);
logical_array = logical (cat (2 , 0, slice(1:end-1) == slice (2:end)));
slice_x = app.x (1:1 , 1:var:end); 
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
hold(app.UIAxes , 'off');
plot(app.UIAxes, app.x, app.data(app.fp1_value, :), 'b');
hold(app.UIAxes , 'on');
plot(app.UIAxes, app.x, app.data(app.fp2_value, :), 'g');
stem(app.UIAxes, slice_x(logical_array), fprow_max*ones(1,nnz(logical_array)), 'Color', 'r', 'Marker', '.');
%stem(app.UIAxes, slice_x(logical_array), fprow_max*ones(1,sum (logical_array)),'r *')


        end
        
    end


    methods (Access = private)

        % Callback function: Button, UIAxes
        function UIAxesButtonDown(app, event)
% filename =uigetfile('*./MATLAB Drive');
 %%[filename,filepath] = uigetfile('*./DESKTOP');
  [filename,filepath] = uigetfile('*./DESKTOP');
%%[ app.x, app.y, app.data] = read_coc_map(fullfile(filepath,filename)); 
%%%%[ app.x, app.y, app.data] = load('data.mat');
%[app.x,app.y,app.z, app.data] = read_matlab_file(fullfile(filepath,filename));
[app.x,app.y, app.data] = read_matlab_file(fullfile(filepath,filename));
            miny1 = min(app.y);
 % change by Dave           miny1 = 1;
            maxy1 = max(app.y);
 % change my Dave            maxy1 = numel(app.y);
 app.Focus_point_1Slider.Limits = [miny1, maxy1];
%%% app.Focus_point_1Slider.MajorTickLabels = {num2str(app.y')}; 
 app.Focus_point_2Slider.Limits = [miny1, maxy1]; 
%%% app.Focus_point_2Slider.MajorTickLabels = {num2str(app.y')}; 
 
 figure(1);
 surf(app.x,app.y,app.data);
 
 shading interp;
 title('Blur radius chart');
 xlabel ('range ');
 ylabel ('d_o  ');
 zlabel ('Blur Radius (px)');
%[app.fp1_value , app.fp2_value ] = update_plot(app);
   figure(3);
   plot(app.x);


        %  app.UITable.data = [app.x,app.y,app.z, app.data]  ;
%        tt=  {'ragne',app.x,'focus',app.y ,'zoom',app.z,'data', app.data}; 
%           app.UITable.Data =tt;
%             
             % Add data to the Table UI Component
       %     app.UITable.Data = t; 
          
         
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: DivisionsEditField
        function DivisionsEditFieldValueChanged(app, event)
           
         update_plot(app);


        end

        % Value changed function: Focus_point_1Slider
        function Focus_point_1SliderValueChanged(app, event)
            
            update_plot(app);
             
        end

        % Value changed function: Focus_point_2Slider
        function Focus_point_2SliderValueChanged(app, event)
            
            update_plot(app); 
         
        end

        % Callback function
        function min_valueEditFieldValueChanged(app, event)
      
 
        end

        % Callback function
        function max_valueEditFieldValueChanged(app, event)


        end

        % Callback function
        function stepEditFieldValueChanged(app, event)
 
        end

        % Callback function
        function From_rangeEditFieldValueChanged(app, event)

        end

        % Callback function
        function To_rangeEditFieldValueChanged(app, event)
        
           
        end

        % Callback function
        function ListBoxValueChanged(app, event)
       
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
        update_plot(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X Range')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Button.Text = 'Button';
            app.DivisionsEditFieldLabel = uilabel(app.UIFigure);
            app.DivisionsEditFieldLabel.Text = 'Divisions';
            app.DivisionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DivisionsEditField.Limits = [1 Inf];
            app.DivisionsEditField.ValueChangedFcn = createCallbackFcn(app, @DivisionsEditFieldValueChanged, true);
            app.DivisionsEditField.Value = 10;
            app.Focus_point_1SliderLabel = uilabel(app.UIFigure);
            app.Focus_point_1SliderLabel.Text = 'Focus_point_1';
            app.Focus_point_1Slider = uislider(app.UIFigure);
            app.Focus_point_1Slider.Limits = [20 100];
            app.Focus_point_1Slider.ValueChangedFcn = createCallbackFcn(app, @Focus_point_1SliderValueChanged, true);
            app.Focus_point_1Slider.Value = 20;
            app.Focus_point_2SliderLabel = uilabel(app.UIFigure);
            app.Focus_point_2SliderLabel.Text = 'Focus_point_2';
            app.Focus_point_2Slider = uislider(app.UIFigure);
            app.Focus_point_2Slider.Limits = [500 1000];
            app.Focus_point_2Slider.ValueChangedFcn = createCallbackFcn(app, @Focus_point_2SliderValueChanged, true);
            app.Focus_point_2Slider.Value = 595;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end