classdef camera_settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LoadDataButton           matlab.ui.control.StateButton
        DivisionsEditFieldLabel  matlab.ui.control.Label
        DivisionsEditField       matlab.ui.control.NumericEditField
        Point1Panel              matlab.ui.container.Panel
        FocusLabel               matlab.ui.control.Label
        Focus_point_1Slider      matlab.ui.control.Slider
        ZoomLabel                matlab.ui.control.Label
        Zoom_point_1Slider       matlab.ui.control.Slider
        Label                    matlab.ui.control.Label
        fp1_edit                 matlab.ui.control.NumericEditField
        EditField_2Label         matlab.ui.control.Label
        zm1_edit                 matlab.ui.control.NumericEditField
        Point2Panel              matlab.ui.container.Panel
        FocusLabel_2             matlab.ui.control.Label
        Focus_point_2Slider      matlab.ui.control.Slider
        ZoomLabel_2              matlab.ui.control.Label
        Zoom_point_2Slider       matlab.ui.control.Slider
        EditField_3Label         matlab.ui.control.Label
        fp2_edit                 matlab.ui.control.NumericEditField
        EditField_4Label         matlab.ui.control.Label
        zm2_edit                 matlab.ui.control.NumericEditField
        UIAxes                   matlab.ui.control.UIAxes
    end

properties(Access = public) 
    data = [];
    x = [];
    y = [];
    z = [];
    min_x = 0;
    max_x = 0;
    fig = 0;
end

    methods (Access = private)
    
        function update_plot(app)
            
            % get the slider values
            fp1_value = floor(app.Focus_point_1Slider.Value);   % - app.Focus_point_1Slider.Limits(1) + 1);
            fp2_value = floor(app.Focus_point_2Slider.Value);   % - app.Focus_point_2Slider.Limits(1) + 1);
            zm1_value = floor(app.Zoom_point_1Slider.Value);
            zm2_value = floor(app.Zoom_point_2Slider.Value);
            
            %display  numerical value in boxes
            
           %app.fp1_edit.Value =  ( size_y + fp1_value -1);   
            app.fp1_edit.Value =  app.y(1,fp1_value);  %% y focus  z is too zoom  range to x 
            app.zm1_edit.Value =  app.z(1,zm1_value);
          
            app.fp2_edit.Value =  app.y(1,fp2_value);
            app.zm2_edit.Value =  app.z(1,zm2_value);
              
            
            % get the rows associated with the data
            fp1_row = app.data(fp1_value, :, zm1_value);
            fp2_row = app.data(fp2_value, :, zm2_value);      
            
            % get max value of the two rows plotted
            fp1_row_max = max(fp1_row);
            fp2_row_max = max(fp2_row);
            fprow_max = max(fp1_row_max,fp2_row_max);
            
            % absolute difference between fp1_row and fp2_row store as fp_diff
            fp_diff = abs( fp1_row - fp2_row ) ; 
            
            % get the non_unique pairs division number and generate the
            % right data slices
            div_var = app.DivisionsEditField.Value; 
            slice = fp_diff ( 1:1 , 1:div_var:end);
            slice_x = app.x (1:1 , 1:div_var:end); 
            
            % geenrate the non-unique matching data points per division
            logical_array = logical (cat (2 , 0, slice(1:end-1) == slice (2:end)));
            
            % plot the two focus/zoom points and the non-unique values per division
            hold(app.UIAxes , 'off');
            plot(app.UIAxes, app.x, app.data(fp1_value, :, zm1_value), 'b');
            hold(app.UIAxes , 'on');
            plot(app.UIAxes, app.x, app.data(fp2_value, :, zm1_value), 'g');
            stem(app.UIAxes, slice_x(logical_array), fprow_max*ones(1,nnz(logical_array)), 'Color', 'r', 'Marker', '.');
            
            xlim(app.UIAxes, [app.min_x, app.max_x]);
            ylim(app.UIAxes, [0, 100]);

        end
        
        %--------------------------------------------------------------------------
        function ui_pop(app)
            drawnow;
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end 
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LoadDataButton
        function load_data_callback(app, event)
            
            file_filter = {'*.mat','MATLAB Files';'*.csv','CSV Files';'*.*','All Files' };
            [filename,filepath] = uigetfile(file_filter, 'Select Data File', pwd);
            
            ui_pop(app);
            
            if(filepath == 0)
                return;
            end
                        
            % load the data in             
            %[app.x, app.y, app.data] = read_coc_map(fullfile(filepath,filename)); 
            [app.x, app.y, app.z , app.data] = read_matlab_file(fullfile(filepath,filename));
            
            app.min_x = min(app.x);
            app.max_x = max(app.x);
                       
            miny1 = 1;
            maxy1 = numel(app.y);
            n_focus = min(maxy1, 21);       % 21 => number of major ticks
            focus_tick_step = floor((maxy1 - miny1)/(n_focus - 1) + 0.5);
            
            minz1= 1;
            maxz1=numel(app.z);
            n_zoom = min(maxz1, 21);        % 21 => number of major ticks
            zoom_tick_step = floor((maxz1 - minz1)/(n_zoom - 1) + 0.5);
            
            % set the focus slider 1 & 2 limits and ticks
            app.Focus_point_1Slider.Limits = [miny1, maxy1];
            app.Focus_point_2Slider.Limits = [miny1, maxy1];
            
            app.Focus_point_1Slider.MajorTicks = miny1:focus_tick_step:maxy1;
            app.Focus_point_2Slider.MajorTicks = miny1:focus_tick_step:maxy1;
            
            app.Focus_point_1Slider.MajorTickLabels = cellstr(num2str(app.y(1:focus_tick_step:end)'));
            app.Focus_point_2Slider.MajorTickLabels = cellstr(num2str(app.y(1:focus_tick_step:end)'));
            
            app.Focus_point_1Slider.Value = miny1;
            app.Focus_point_2Slider.Value = maxy1;
            app.fp1_edit.Value = app.y(1, miny1);
            app.fp2_edit.Value = app.y(1, maxy1);
            
            
            % set the zoom slider 1 & 2 limits and ticks
            app.Zoom_point_1Slider.Limits = [minz1,maxz1];
            app.Zoom_point_2Slider.Limits = [minz1,maxz1];
            
            app.Zoom_point_1Slider.MajorTicks = minz1:zoom_tick_step:maxz1;  % focus_tick_step_z =tbd
            app.Zoom_point_2Slider.MajorTicks = minz1:zoom_tick_step:maxz1;  % focus_tick_step_z =tbd
     
            app.Zoom_point_1Slider.MajorTickLabels = cellstr(num2str(app.z(1:zoom_tick_step:end)')); %focus_tick_step_z =tbd
            app.Zoom_point_2Slider.MajorTickLabels = cellstr(num2str(app.z(1:zoom_tick_step:end)')); %focus_tick_step_z =tbd

            app.Zoom_point_1Slider.Value = minz1;
            app.Zoom_point_2Slider.Value = maxz1;
            app.zm1_edit.Value = app.z(1, minz1);
            app.zm2_edit.Value = app.z(1, maxz1);
            
            
            figure(1);
            surf(app.x, app.y, app.data(:, :, 1));           
            shading('interp');
            title('Blur Radius Chart');
            xlabel('Range');
            ylabel('Focus');
            zlabel('Blur Radius (px)');
            
            update_plot(app);
          
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: DivisionsEditField
        function DivisionsEditFieldValueChanged(app, event)
           
         update_plot(app);


        end

        % Value changed function: Focus_point_1Slider
        function Focus_point_1SliderValueChanged(app, event)
            
            update_plot(app);
             
        end

        % Value changed function: Focus_point_2Slider
        function Focus_point_2SliderValueChanged(app, event)
            
            update_plot(app); 
         
        end

        % Value changed function: fp1_edit
        function fp1_editValueChanged(app, event)
             update_plot(app);
            
        end

        % Size changed function: Point1Panel
        function Point1PanelSizeChanged(app, event)
           
            
        end

        % Value changed function: zm1_edit
        function zm1_editValueChanged(app, event)
             update_plot(app);
            
        end

        % Value changed function: fp2_edit
        function fp2_editValueChanged(app, event)
            
             update_plot(app);
        end

        % Value changed function: zm2_edit
        function zm2_editValueChanged(app, event)
            
             update_plot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LoadDataButton = uibutton(app.UIFigure, 'state');
            app.LoadDataButton.ValueChangedFcn = createCallbackFcn(app, @load_data_callback, true);
            app.LoadDataButton.Text = 'Load Data';
            app.DivisionsEditFieldLabel = uilabel(app.UIFigure);
            app.DivisionsEditFieldLabel.Text = 'Divisions';
            app.DivisionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DivisionsEditField.Limits = [1 Inf];
            app.DivisionsEditField.ValueDisplayFormat = '%.0f';
            app.DivisionsEditField.ValueChangedFcn = createCallbackFcn(app, @DivisionsEditFieldValueChanged, true);
            app.DivisionsEditField.Value = 1;
            app.Point1Panel = uipanel(app.UIFigure);
            app.Point1Panel.Title = 'Point 1';
            app.Point1Panel.SizeChangedFcn = createCallbackFcn(app, @Point1PanelSizeChanged, true);
            app.FocusLabel = uilabel(app.Point1Panel);
            app.FocusLabel.Text = 'Focus';
            app.Focus_point_1Slider = uislider(app.Point1Panel);
            app.Focus_point_1Slider.Limits = [500 1000];
            app.Focus_point_1Slider.ValueChangedFcn = createCallbackFcn(app, @Focus_point_1SliderValueChanged, true);
            app.Focus_point_1Slider.Value = 500;
            app.ZoomLabel = uilabel(app.Point1Panel);
            app.ZoomLabel.Text = 'Zoom';
            app.Zoom_point_1Slider = uislider(app.Point1Panel);
            app.Zoom_point_1Slider.Limits = [1 100];
            app.Zoom_point_1Slider.Value = 1;
            app.Label = uilabel(app.Point1Panel);
            app.Label.Text = '';
            app.fp1_edit = uieditfield(app.Point1Panel, 'numeric');
            app.fp1_edit.Limits = [0 Inf];
            app.fp1_edit.ValueDisplayFormat = '%.0f';
            app.fp1_edit.ValueChangedFcn = createCallbackFcn(app, @fp1_editValueChanged, true);
            app.fp1_edit.Value = 500;
            app.EditField_2Label = uilabel(app.Point1Panel);
            app.EditField_2Label.Text = '';
            app.zm1_edit = uieditfield(app.Point1Panel, 'numeric');
            app.zm1_edit.Limits = [0 Inf];
            app.zm1_edit.ValueDisplayFormat = '%.0f';
            app.zm1_edit.ValueChangedFcn = createCallbackFcn(app, @zm1_editValueChanged, true);
            app.zm1_edit.Value = 1;
            app.Point2Panel = uipanel(app.UIFigure);
            app.Point2Panel.Title = 'Point 2';
            app.FocusLabel_2 = uilabel(app.Point2Panel);
            app.FocusLabel_2.Text = 'Focus';
            app.Focus_point_2Slider = uislider(app.Point2Panel);
            app.Focus_point_2Slider.Limits = [500 1000];
            app.Focus_point_2Slider.ValueChangedFcn = createCallbackFcn(app, @Focus_point_2SliderValueChanged, true);
            app.Focus_point_2Slider.Value = 500;
            app.ZoomLabel_2 = uilabel(app.Point2Panel);
            app.ZoomLabel_2.Text = 'Zoom';
            app.Zoom_point_2Slider = uislider(app.Point2Panel);
            app.Zoom_point_2Slider.Limits = [1 100];
            app.Zoom_point_2Slider.Value = 1;
            app.EditField_3Label = uilabel(app.Point2Panel);
            app.EditField_3Label.Text = '';
            app.fp2_edit = uieditfield(app.Point2Panel, 'numeric');
            app.fp2_edit.Limits = [0 Inf];
            app.fp2_edit.ValueDisplayFormat = '%.0f';
            app.fp2_edit.ValueChangedFcn = createCallbackFcn(app, @fp2_editValueChanged, true);
            app.fp2_edit.Value = 500;
            app.EditField_4Label = uilabel(app.Point2Panel);
            app.EditField_4Label.Text = '';
            app.zm2_edit = uieditfield(app.Point2Panel, 'numeric');
            app.zm2_edit.Limits = [0 Inf];
            app.zm2_edit.ValueDisplayFormat = '%.0f';
            app.zm2_edit.ValueChangedFcn = createCallbackFcn(app, @zm2_editValueChanged, true);
            app.zm2_edit.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Range')
            ylabel(app.UIAxes, 'Pixel Blur Radius (px)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = camera_settings

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end